classdef DAQConnectorApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        HoloIPEditField       matlab.ui.control.EditField
        HoloIPEditFieldLabel  matlab.ui.control.Label
        RunningLamp           matlab.ui.control.Lamp
        RunningLampLabel      matlab.ui.control.Label
        ConnectedLamp         matlab.ui.control.Lamp
        ConnectedLampLabel    matlab.ui.control.Label
        RUNButton             matlab.ui.control.Button
        CONNECTButton         matlab.ui.control.Button
    end


    properties (Access = private)
        LaserPower % Description
        HoloSocket
        running
        daq
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %%Create DAQ Session
            locations = FrankenScopeRigFile();

            app.daq = daq('ni'); %ni is company name

            app.daq.Rate=20000;
            app.daq.ExternalTriggerTimeout=30000000; %basically never time out
            app.daq.addoutput('Dev1',2,'Voltage');

            app.LaserPower = importdata(locations.PowerCalib,'LaserPower');
        end

        % Button pushed function: CONNECTButton
        function CONNECTButtonPushed(app, event)
            
         disp('establishing socket connection with Holo Comp')
            holoIP = app.HoloIPEditField.Value;% '192.168.10.102';
            app.HoloSocket = msconnect(holoIP,42118);

            invar=[];
            while ~strcmp(invar,'A')
                invar = msrecv(app.HoloSocket,0.1);
                disp('Not Recieved')
            end
            disp('Recieved')

            sendVar ='B';
            mssend(app.HoloSocket,sendVar);
            app.ConnectedLamp.Color = 'green';
            disp('Input from Holo Computer confirmed');

        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            app.running = true;
            app.RunningLamp.Color = 'green';
            while app.running
                invar=msrecv(app.HoloSocket,.01); %order: power, DE, nTargets
                if ~isempty(invar) && ~ischar(invar)
                    fprintf('Update Power: ')

                    PowerRequest = (invar(1)*invar(3))/invar(2);
                    if PowerRequest ==0
                        Volt=0;
                    else
                        Volt = function_EOMVoltage(app.LaserPower.EOMVoltage, app.LaserPower.PowerOutputTF,PowerRequest);
                    end
                    fprintf([num2str(PowerRequest,2) ' W. -> ' num2str(Volt,2) ' V. ']);

                    Fs = s.Rate;
                    trialLength = 0.10;%s
                    eomOffset = -0.15;

                    [output] = makepulseoutputs(1, 1, trialLength*Fs, Volt ,1, Fs, trialLength);

                    output(output==0)=eomOffset;
                    output(end-20:end)=[];


                    app.daq.queueOutputData([output]);
                    app.daq.startForeground;

                    mssend(app.HoloSocket,'gotit');

                    fprintf(['Time since last run ' num2str(toc(tstart),2) 's\n']);
                    tstart=tic;
                end

                if toc(tstart)>timeoutTime || strcmp(invar,'end')
                    app.running = false;
                    app.RunningLamp.Color = 'red';
                    mssend(app.HoloSocket,'kthx');

                end
            end

            [output] = makepulseoutputs(1, 1, trialLength*Fs, eomOffset ,1, Fs, trialLength);
            output(output==0)=eomOffset;
            app.daq.queueOutputData([output]);
            app.daq.startForeground;
            disp('Ended')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CONNECTButton = uibutton(app.UIFigure, 'push');
            app.CONNECTButton.ButtonPushedFcn = createCallbackFcn(app, @CONNECTButtonPushed, true);
            app.CONNECTButton.Text = 'CONNECT';
            app.RUNButton = uibutton(app.UIFigure, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.ConnectedLampLabel = uilabel(app.UIFigure);
            app.ConnectedLampLabel.Text = 'Connected';
            app.ConnectedLamp = uilamp(app.UIFigure);
            app.RunningLampLabel = uilabel(app.UIFigure);
            app.RunningLampLabel.Text = 'Running';
            app.RunningLamp = uilamp(app.UIFigure);
            app.HoloIPEditFieldLabel = uilabel(app.UIFigure);
            app.HoloIPEditFieldLabel.Text = 'Holo IP';
            app.HoloIPEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DAQConnectorApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end