classdef SIConnectorApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        DAQComputerIPEditField       matlab.ui.control.EditField
        DAQComputerIPEditFieldLabel  matlab.ui.control.Label
        RunningLamp                  matlab.ui.control.Lamp
        RunningLampLabel             matlab.ui.control.Label
        ConnectedLamp                matlab.ui.control.Lamp
        ConnectedLampLabel           matlab.ui.control.Label
        RunButton                    matlab.ui.control.Button
        ConnectButton                matlab.ui.control.Button
    end


    properties (Access = private)
        HoloSocket % Description
        running
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)

            disp('establishing socket connection with Holo Comp')
            holoIP = app.DAQComputerIPEditField.Value;% '192.168.10.102';
            app.HoloSocket = msconnect(holoIP,42040);

            invar=[];
            while ~strcmp(invar,'A')
                invar = msrecv(app.HoloSocket,0.1);
                disp('Not Recieved')
            end
            disp('Recieved')

            sendVar ='B';
            mssend(app.HoloSocket,sendVar);
            app.ConnectedLamp.Color = 'green';
            disp('Input from Holo Computer confirmed');
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            %%
            hSI = evalin('base','hSI');
            global autoCalibPlaneToUse

            autoCalibPlaneToUse = 10;

            hSI.hBeams.pzAdjust = 1;
            hSI.hBeams.pzCustom= {@autoCalibSIPowerFun} ;

            hSI.hStackManager.arbitraryZs = [ 0 autoCalibPlaneToUse];
            hSI.hStackManager.numVolumes = 10000;
            hSI.hStackManager.enable =1;

            hSI.extTrigEnable =0;


            % hSI.startGrab();
            %
            % hSI.abort();

            disp('Waiting for cue from HoloComp')
            app.RunningLamp.Color = 'green';
            app.running = true;
            while app.running
                pause(0.1);
                invar =msrecv(app.HoloSocket,0.1);
                if ~isempty(invar) && ~ischar(invar)
                    fprintf(['Update Z Plane ' num2str(invar(1)) ' ']);
                    autoCalibPlaneToUse = invar(1);


                    %         disp('Grab')
                    %         hSI.acqState
                    %
                    %         hSI.startGrab();
                    %         hSI.acqState
                    if invar(2)==0
                        hSI.abort();
                        disp('Aborted')
                    elseif invar(2)==1
                        if strcmpi(hSI.acqState,'idle')
                            hSI.hBeams.pzCustom= {@autoCalibSIPowerFun} ;

                            if invar(1)==0
                                hSI.hStackManager.arbitraryZs = [ 0 5];
                            else
                                hSI.hStackManager.arbitraryZs = [ 0 autoCalibPlaneToUse];
                            end
                            hSI.startGrab();
                            disp('Started')
                        else
                            hSI.abort();
                            hSI.hBeams.pzAdjust = 1;
                            hSI.hBeams.pzCustom= {@autoCalibSIPowerFun} ;

                            if invar(1)==0
                                hSI.hStackManager.arbitraryZs = [ 0 5];
                            else
                                hSI.hStackManager.arbitraryZs = [ 0 autoCalibPlaneToUse];
                            end
                            hSI.startGrab();
                            disp('restarted')
                        end
                    else
                        disp('Unrecognized Command')
                    end


                    mssend(app.HoloSocket,'gotit');

                    % pause(1)
                    %         if invar(2)
                    %             hSI.startGrab();
                    %         else
                    %            hSI.abort();
                    %         end
                end

                if strcmp(invar,'end')
                    disp('end kthx');
                    app.running = false;
                    app.RunningLamp.Color = 'red';
                    mssend(app.HoloSocket,'kthx');
                    hSI.abort();
                end
            end

            %%step2
            disp('Waiting for Auto Command from HoloComp')
            app.running = true;
            app.RunningLamp.Color = 'green';

            invar =msrecv(app.HoloSocket,0.1);
            while ~isempty(invar)
                invar =msrecv(app.HoloSocket,0.1);
            end

            while apprunning
                pause(0.1);
                invar =msrecv(app.HoloSocket,0.1);
                if ~isempty(invar)
                    if strcmp(invar,'end')
                        disp('end kthx');
                        app.RunningLamp.Color = 'red';
                        app.running = false;
                        mssend(app.HoloSocket,'kthx');
                        hSI.abort();
                    else
                        disp('Eval Command Received')
                        try
                            out = eval(invar);
                        catch
                            out='No Output';
                            try
                                disp(invar);
                                eval(invar);
                            catch

                                out='Eval Error';
                            end
                        end
                        if isempty(out)
                            out='-';
                        end
                        disp(out)
                        mssend(app.HoloSocket,out);
                    end
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ConnectedLampLabel = uilabel(app.UIFigure);
            app.ConnectedLampLabel.Text = 'Connected';
            app.ConnectedLamp = uilamp(app.UIFigure);
            app.RunningLampLabel = uilabel(app.UIFigure);
            app.RunningLampLabel.Text = 'Running';
            app.RunningLamp = uilamp(app.UIFigure);
            app.DAQComputerIPEditFieldLabel = uilabel(app.UIFigure);
            app.DAQComputerIPEditFieldLabel.Text = 'DAQ Computer IP';
            app.DAQComputerIPEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SIConnectorApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end