classdef baserLiveView < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        FinishedButton  matlab.ui.control.Button
        UIAxes          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        bas % Description
        running = true
        preview_bit_depth = 'auto';
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, bas)
            app.bas = bas;

            while app.running
                frame = app.bas.grab(1);
                imagesc(app.UIAxes, frame)
                axis(app.UIAxes, 'image');
                if isa(app.preview_bit_depth, 'double')
                    caxis(app.UIAxes, [0 app.preview_bit_depth])
                end
                colorbar(app.UIAxes);
            end
            % res = app.bas.vid.VideoResolution;
            % nBands = app.bas.vid.NumberOfBands;
            % hImage = image(app.UIAxes, zeros(res(2), res(1), nBands));
            % colorbar(app.UIAxes);
            % preview(app.bas.vid, hImage);
        end

        % Button pushed function: FinishedButton
        function FinishedButtonPushed(app, event)
            % app.bas.vid.stoppreview();
            app.running = false;
            closereq();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.FinishedButton = uibutton(app.UIFigure, 'push');
            app.FinishedButton.ButtonPushedFcn = createCallbackFcn(app, @FinishedButtonPushed, true);
            app.FinishedButton.Text = 'Finished';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = baserLiveView(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end