classdef calibrateHoloPowerApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        sendholoButton      matlab.ui.control.Button
        SLMYEditField       matlab.ui.control.NumericEditField
        SLMYEditFieldLabel  matlab.ui.control.Label
        SLMXEditField       matlab.ui.control.NumericEditField
        SLMXEditFieldLabel  matlab.ui.control.Label
        DoneButton          matlab.ui.control.Button
        PowermWKnob         matlab.ui.control.Knob
        PowermWKnobLabel    matlab.ui.control.Label
        histo               matlab.ui.control.UIAxes
        preview             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        bas % Description
        slm % Description
        Setup % Description
        running = true
    end
    
    methods (Access = private)
        function setPowerLevel(app, pwr)
            mssend(app.masterSocket, [pwr/1000, 1, 1]);
        end
        
        function updatehisto(app, img)
            histogram(app.histo, img(:), 'Normalization', 'probability');
            xticks(app.histo, 0:20:50);
            xlim(app.histo, [0, 50]);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, bas, slm, Setup)
            app.bas = bas;
            app.slm = slm;
            app.Setup = Setup;
            app.masterSocket = masterSocket;
            app.SLMYEditField.Value = 0.5;
            app.SLMXEditField.Value = 0.5;
            app.PowermWKnob.Value = 10;

            while app.running
                frame = app.bas.grab(1);
                imagesc(app.preview, frame)
                axis(app.preview, 'image');
                % if isa(app.preview_bit_depth, 'double')
                %     caxis(app.preview, [0 app.preview_bit_depth])
                % end
                colorbar(app.preview);
                app.updatehisto(frame)
            end
        end

        % Value changed function: PowermWKnob
        function PowermWKnobValueChanged(app, event)
            pwr = app.PowermWKnob.Value;
            app.setPowerLevel(pwr);
        end

        % Button pushed function: sendholoButton
        function sendholoButtonPushed(app, event)
            slmCoords = [app.SLMYEditField.Value, app.SLMXEditField.Value, 0, 1];

            DEestimate = DEfromSLMCoords(slmCoords);
            disp(['Diffraction Estimate for this spot is: ' num2str(DEestimate)])

            Holo = function_Make_3D_SHOT_Holos(app.slm.add_slm(app.Setup), slmCoords);
            app.slm.feed(Holo);
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            closereq();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.preview = uiaxes(app.UIFigure);
            app.preview.XTick = [];
            app.preview.YTick = [];
            app.histo = uiaxes(app.UIFigure);
            app.histo.XTick = [];
            app.histo.YTick = [];
            app.PowermWKnobLabel = uilabel(app.UIFigure);
            app.PowermWKnobLabel.Text = 'Power (mW)';
            app.PowermWKnob = uiknob(app.UIFigure, 'continuous');
            app.PowermWKnob.ValueChangedFcn = createCallbackFcn(app, @PowermWKnobValueChanged, true);
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.SLMXEditFieldLabel = uilabel(app.UIFigure);
            app.SLMXEditFieldLabel.Text = 'SLM X';
            app.SLMXEditField = uieditfield(app.UIFigure, 'numeric');
            app.SLMYEditFieldLabel = uilabel(app.UIFigure);
            app.SLMYEditFieldLabel.Text = 'SLM Y';
            app.SLMYEditField = uieditfield(app.UIFigure, 'numeric');
            app.sendholoButton = uibutton(app.UIFigure, 'push');
            app.sendholoButton.ButtonPushedFcn = createCallbackFcn(app, @sendholoButtonPushed, true);
            app.sendholoButton.Text = 'send holo';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = calibrateHoloPowerApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end