classdef findFocalPlaneApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        DoneButton        matlab.ui.control.Button
        testsutterButton  matlab.ui.control.Button
        UIAxes            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        bas % Description
        sutter
        SISocket % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, bas, sutter, SISocket)
            app.sutter = sutter;
            
            app.SISocket = SISocket;

            app.bas = bas;
            res = app.bas.vid.VideoResolution;
            nBands = app.bas.vid.NumberOfBands;
            hImage = image(app.UIaxes, zeros(res(2), res(1), nBands));
            colorbar(app.UIAxes);
            app.bas.vid.preview(hImage);
            disp('Find Focal Plane, Center and Zero the Sutter')
            disp('Leave the focus at Zoom 1. at a power that is less likely to bleach (14% 25mW)') %25% 8/16/19
            disp('Don''t forget to use Ultrasound Gel on the objective so it doesn''t evaporate')
        end

        % Button pushed function: testsutterButton
        function testsutterButtonPushed(app, event)
            app.sutter.setRef();
            mssend(app.SISocket, [0, 0]);
            app.sutter.moveZ(100)
            pause(3)
            app.sutter.moveToRef();
            app.DoneButton.Enable = 1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.testsutterButton = uibutton(app.UIFigure, 'push');
            app.testsutterButton.ButtonPushedFcn = createCallbackFcn(app, @testsutterButtonPushed, true);
            app.testsutterButton.Text = 'test sutter';
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.Enable = 'off';
            app.DoneButton.Text = 'Done';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = findFocalPlaneApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end