classdef register_3DSHOT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Panel2                    matlab.ui.container.Panel
        findfocalplaneButton      matlab.ui.control.Button
        setpowerlevelButton       matlab.ui.control.Button
        findobjectiveButton       matlab.ui.control.Button
        DAQConnectionLamp         matlab.ui.control.Lamp
        DAQConnectionLampLabel    matlab.ui.control.Label
        SIConnectionLamp          matlab.ui.control.Lamp
        SIConnectionLampLabel     matlab.ui.control.Label
        optotune                  matlab.ui.container.Panel
        informationaboutfitandstuffLabel  matlab.ui.control.Label
        closeoptotuneplotsButton  matlab.ui.control.Button
        showoptotuneplotsButton   matlab.ui.control.Button
        OptotuneButton            matlab.ui.control.Button
        ConnecttoDAQButton        matlab.ui.control.Button
        ConnecttoSIButton         matlab.ui.control.Button
    end


    properties (Access = private)
        things % just a struct to collect things to pass around
        ready2run = [false, false, false]; % setups
    end
    
    methods (Access = private)
        
        function checkReady2Run(app)
            if all(app.ready2run)
                app.optotune.Enable = true;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            makePaths();

            disp('Setting up stuff...');

            Setup = function_loadparameters2();
            Setup.CGHMethod = 2;
            Setup.GSoffset = 0;
            Setup.verbose = 0;
            Setup.useGPU = 0;

            if Setup.useGPU
                parallel.gpu.enableCUDAForwardCompatibility(true)
                disp('Getting gpu...'); %this can sometimes take a while at initialization
                g = gpuDevice;

            end

            app.things.Setup = Setup;
            app.things.slm = MeadowlarkOneK();
            app.things.slm.stop();
            app.things.slm.start();

            app.things.sutter = sutterController();

            app.things.bas = bascam();
            app.things.bas.start()
            disp('Ready')

            %% should I add stuff here?
            dbstop('if error') % allow debugger to run?

        end

        % Button pushed function: OptotuneButton
        function OptotuneButtonPushed(app, event)
            ao = AlignOptotune(app.things.SISocket, app.things.masterSocket, app.things.sutter, app.things.bas);
            SIdepthImages = ao.optoSearch();
            SIfits = ao.doFits(SIdepthImages);
        end

        % Button pushed function: ConnecttoSIButton
        function ConnecttoSIButtonPushed(app, event)
            %% connect to ScanImage computer

            % run this code first, then 'autoCalibSI' on SI computer
            fprintf('Waiting for msocket communication to ScanImage Computer... ')
            % then wait for a handshake
            srvsock2 = mslisten(42039);
            app.things.SISocket = msaccept(srvsock2,15);
            msclose(srvsock2);
            sendVar = 'A';
            mssend(app.things.SISocket, sendVar);

            invar = [];

            while ~strcmp(invar,'B')
                invar = msrecv(app.things.SISocket,.1);
            end
            app.SIConnectionLamp.Color = 'green';
            fprintf('done.\r')
        end

        % Button pushed function: ConnecttoDAQButton
        function ConnecttoDAQButtonPushed(app, event)
            %% connect to DAQ computer
            % add button here?
            %run this first then code on daq
            disp('Waiting for msocket communication From DAQ... ')
            %then wait for a handshake
            srvsock = mslisten(42118);
            app.things.masterSocket = msaccept(srvsock,15);
            msclose(srvsock);
            sendVar = 'A';
            mssend(app.things.masterSocket, sendVar);

            invar = [];

            while ~strcmp(invar,'B')
                invar = msrecv(app.things.masterSocket,.5);
            end
            app.DAQConnectionLamp = 'green';
            fprintf('done.\r')
        end

        % Button pushed function: findobjectiveButton
        function findobjectiveButtonPushed(app, event)
            baserLiveView(app.things.bas)
            app.ready2run(1) = true;
            app.checkReady2run();
        end

        % Button pushed function: setpowerlevelButton
        function setpowerlevelButtonPushed(app, event)
            calibrateHoloPowerApp(app.things.bas, app.things.slm, app.things.Setup);
            app.ready2run(2) = true;
            app.checkReady2Run();
        end

        % Button pushed function: findfocalplaneButton
        function findfocalplaneButtonPushed(app, event)
            findFocalPlaneApp(app.things.bas, app.things.sutter, app.things.SISocket);
            app.ready2run(3) = true;
            app.checkReady2Run();
        end

        % Button pushed function: showoptotuneplotsButton
        function showoptotuneplotsButtonPushed(app, event)
            app.showplots('optotune');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ConnecttoSIButton = uibutton(app.UIFigure, 'push');
            app.ConnecttoSIButton.ButtonPushedFcn = createCallbackFcn(app, @ConnecttoSIButtonPushed, true);
            app.ConnecttoSIButton.Text = 'Connect to SI';
            app.ConnecttoDAQButton = uibutton(app.UIFigure, 'push');
            app.ConnecttoDAQButton.ButtonPushedFcn = createCallbackFcn(app, @ConnecttoDAQButtonPushed, true);
            app.ConnecttoDAQButton.Text = 'Connect to DAQ';
            app.optotune = uipanel(app.UIFigure);
            app.optotune.Enable = 'off';
            app.optotune.Title = 'Panel';
            app.OptotuneButton = uibutton(app.optotune, 'push');
            app.OptotuneButton.ButtonPushedFcn = createCallbackFcn(app, @OptotuneButtonPushed, true);
            app.OptotuneButton.Text = 'Optotune';
            app.showoptotuneplotsButton = uibutton(app.optotune, 'push');
            app.showoptotuneplotsButton.ButtonPushedFcn = createCallbackFcn(app, @showoptotuneplotsButtonPushed, true);
            app.showoptotuneplotsButton.Text = 'show plots';
            app.closeoptotuneplotsButton = uibutton(app.optotune, 'push');
            app.closeoptotuneplotsButton.Text = 'close plots';
            app.informationaboutfitandstuffLabel = uilabel(app.optotune);
            app.informationaboutfitandstuffLabel.Text = 'information about fit and stuff';
            app.SIConnectionLampLabel = uilabel(app.UIFigure);
            app.SIConnectionLampLabel.Text = 'SI Connection';
            app.SIConnectionLamp = uilamp(app.UIFigure);
            app.DAQConnectionLampLabel = uilabel(app.UIFigure);
            app.DAQConnectionLampLabel.Text = 'DAQ Connection';
            app.DAQConnectionLamp = uilamp(app.UIFigure);
            app.Panel2 = uipanel(app.UIFigure);
            app.Panel2.Title = 'Panel2';
            app.findobjectiveButton = uibutton(app.Panel2, 'push');
            app.findobjectiveButton.ButtonPushedFcn = createCallbackFcn(app, @findobjectiveButtonPushed, true);
            app.findobjectiveButton.Text = 'find objective';
            app.setpowerlevelButton = uibutton(app.Panel2, 'push');
            app.setpowerlevelButton.ButtonPushedFcn = createCallbackFcn(app, @setpowerlevelButtonPushed, true);
            app.setpowerlevelButton.Text = 'set power level';
            app.findfocalplaneButton = uibutton(app.Panel2, 'push');
            app.findfocalplaneButton.ButtonPushedFcn = createCallbackFcn(app, @findfocalplaneButtonPushed, true);
            app.findfocalplaneButton.Text = 'find focal plane';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = register_3DSHOT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end